classdef UWI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        SigmaEditField                matlab.ui.control.NumericEditField
        AlphaEditField                matlab.ui.control.NumericEditField
        EditField                     matlab.ui.control.NumericEditField
        UNDERWATERIMAGEENHANCEMENTLabel_2  matlab.ui.control.Label
        NoreferenceimageQualityscoreofSharpenedImageEditField  matlab.ui.control.NumericEditField
        NoreferenceimageQualityscoreofSharpenedImageLabel  matlab.ui.control.Label
        NoreferenceimageQualityscoreofOriginalImageEditField  matlab.ui.control.NumericEditField
        eferenceimageQualityscoreofOriginalImageLabel  matlab.ui.control.Label
        MeansquarederrorMSEEditField  matlab.ui.control.NumericEditField
        MeansquarederrorMSEEditFieldLabel  matlab.ui.control.Label
        StructuralsimilaritySSIMindexEditField  matlab.ui.control.NumericEditField
        StructuralsimilaritySSIMindexLabel  matlab.ui.control.Label
        PeakSNREditField              matlab.ui.control.NumericEditField
        PeakSNREditFieldLabel         matlab.ui.control.Label
        IMAGEQUALITYMATRIXLabel       matlab.ui.control.Label
        TECHNIQUEDropDownLabel        matlab.ui.control.Label
        SNREditField                  matlab.ui.control.NumericEditField
        SNREditFieldLabel             matlab.ui.control.Label
        CONTROLSLabel                 matlab.ui.control.Label
        TECHNIQUEDropDown             matlab.ui.control.DropDown
        IterationSlider               matlab.ui.control.Slider
        IterationSliderLabel          matlab.ui.control.Label
        SigmaKnob                     matlab.ui.control.Knob
        SigmaKnobLabel                matlab.ui.control.Label
        CorrectionFactorAlphaSlider   matlab.ui.control.Slider
        CorrectionFactorAlphaSliderLabel  matlab.ui.control.Label
        INSERTButton                  matlab.ui.control.Button
        CONVERTButton                 matlab.ui.control.Button
        Label                         matlab.ui.control.Label
        UIAxes4                       matlab.ui.control.UIAxes
        UIAxes3                       matlab.ui.control.UIAxes
        UIAxes2                       matlab.ui.control.UIAxes
        UIAxes                        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        image % Description
        Isharp
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: INSERTButton
        function ImgSelect(app, event)
            [file,path] = uigetfile('*.*');
            if isequal(file,0)
                figure(app.UIFigure);
                return;
            end
            figure(app.UIFigure);
            app.image = imread(fullfile(path,file));
            imshow(app.image,'parent',app.UIAxes);
        end

        % Callback function: CONVERTButton, SNREditField
        function convert(app, event)
            rgbImage=double(app.image)/255;
            %grayImage = rgb2gray(rgbImage);
            Ir = rgbImage(:,:,1);
            Ig = rgbImage(:,:,2);
            Ib = rgbImage(:,:,3);
            
            R = app.image(:,:,1);
            G = app.image(:,:,2);
            B = app.image(:,:,3);

            [yRed, x] = imhist(R);
            [yGreen, x] = imhist(G);
            [yBlue, x] = imhist(B);
            plot(app.UIAxes3,x, yRed, 'Red', x, yGreen, 'Green', x, yBlue, 'Blue');

            Ir_mean = mean(Ir, 'all');
            Ig_mean = mean(Ig, 'all');
            Ib_mean = mean(Ib, 'all');
            
            alpha = app.CorrectionFactorAlphaSlider.Value;
            Irc = Ir + alpha*(Ig_mean - Ir_mean);
            Ibc = Ib + alpha*(Ig_mean - Ib_mean);
            app.AlphaEditField.Value = alpha;

            I = cat(3, Irc, Ig, Ibc);
            I_lin = rgb2lin(I);
            percentiles = 5;
            illuminant = illumgray(I_lin,percentiles);
            I_lin = chromadapt(I_lin,illuminant,'ColorSpace','linear-rgb');
            Iwb = lin2rgb(I_lin);

            
            sigma = app.SigmaKnob.Value;
            app.SigmaEditField.Value = sigma;
            Igauss = Iwb;
            N = app.IterationSlider.Value;
            app.EditField.Value = N;
            for iter=1: N
                Igauss =  imgaussfilt(Igauss,sigma);
                Igauss = min(Iwb, Igauss);
            end

            gain = 1;

            Norm = (Iwb-gain*Igauss);

            for n = 1:3
                Norm(:,:,n) = histeq(Norm(:,:,n)); 
            end
            app.Isharp = (Iwb + Norm)/2;


            Igamma = double(imadjust(Iwb,[],[],2));
            
            % Lapacian contrast weight 
Isharp_lab = double(rgb2lab(app.Isharp));
Igamma_lab = double(rgb2lab(Igamma));

% input1
R1 = double(Isharp_lab(:, :, 1)) / 255;

% calculate laplacian contrast weight
WC1 = sqrt((((app.Isharp(:,:,1)) - (R1)).^2 + ((app.Isharp(:,:,2)) - (R1)).^2 + ((app.Isharp(:,:,3)) - (R1)).^2) / 3);

% calculate the saliency weight
WS1 = saliency_detection(app.Isharp);
WS1 = WS1/max(WS1,[],'all');

% calculate the saturation weight
WSAT1 = sqrt(1/3*((app.Isharp(:,:,1)-R1).^2+(app.Isharp(:,:,2)-R1).^2+(app.Isharp(:,:,3)-R1).^2));

%figure('name', 'Image 1 weights');
%imshow([WC1 , WS1, WSAT1]);

% input2
R2 = double(Igamma_lab(:, :, 1)) / 255;

% calculate laplacian contrast weight
WC2 = sqrt((((Igamma(:,:,1)) - (R2)).^2 + ((Igamma(:,:,2)) - (R2)).^2 + ((Igamma(:,:,3)) - (R2)).^2) / 3);

% calculate the saliency weight
WS2 = saliency_detection(Igamma);
WS2 = WS2/max(WS2,[],'all');

% calculate the saturation weight
WSAT2 = sqrt(1/3*((Igamma(:,:,1)-R1).^2+(Igamma(:,:,2)-R1).^2+(Igamma(:,:,3)-R1).^2));

%figure('name', 'Image 2 weights');
%imshow([WC2 , WS2, WSAT2]);

% calculate the normalized weight
W1 = (WC1 + WS1 + WSAT1+0.1) ./ (WC1 + WS1 + WSAT1 + WC2 + WS2 + WSAT2+0.2);
W2 = (WC2 + WS2 + WSAT2+0.1) ./ (WC1 + WS1 + WSAT1 + WC2 + WS2 + WSAT2+0.2);

%% Naive fusion
R = W1.*app.Isharp+W2.*Igamma;
%figure('name', 'Naive Fusion');
%imshow([I, Iwb, app.Isharp, Igamma, R]);

%% Multi scale fusion.
 img1 = app.Isharp;
 img2 = Igamma;

% calculate the gaussian pyramid
level = 10;
Weight1 = gaussian_pyramid(W1, level);
Weight2 = gaussian_pyramid(W2, level);

% calculate the laplacian pyramid
% input1
R1 = laplacian_pyramid(app.Isharp(:, :, 1), level);
G1 = laplacian_pyramid(app.Isharp(:, :, 2), level);
B1 = laplacian_pyramid(app.Isharp(:, :, 3), level);

% input2
R2 = laplacian_pyramid(Igamma(:, :, 1), level);
G2 = laplacian_pyramid(Igamma(:, :, 2), level);
B2 = laplacian_pyramid(Igamma(:, :, 3), level);

% fusion
for k = 1 : level
   Rr{k} = Weight1{k} .* R1{k} + Weight2{k} .* R2{k};
   Rg{k} = Weight1{k} .* G1{k} + Weight2{k} .* G2{k};
   Rb{k} = Weight1{k} .* B1{k} + Weight2{k} .* B2{k};
end

% reconstruct & output
R = pyramid_reconstruct(Rr);
G = pyramid_reconstruct(Rg);
B = pyramid_reconstruct(Rb);
fusion = cat(3, R, G, B);

r = fusion(:,:,1);
g = fusion(:,:,2);
b = fusion(:,:,3);

R = adapthisteq(r, 'NumTiles', [2,2], 'ClipLimit', 0.001);
G = adapthisteq(g, 'NumTiles', [2,2], 'ClipLimit', 0.001);
B = adapthisteq(b, 'NumTiles', [2,2], 'ClipLimit', 0.001);
CLAHE = cat(3, R, G, B);

imshow(CLAHE,'parent',app.UIAxes2);
%%

switch app.TECHNIQUEDropDown.Value
                case 'White Balancing'
                    imshow(Iwb,'parent',app.UIAxes4);

                case 'Gamma Correction'
                    imshow(Igamma,'parent',app.UIAxes4);
                
                case 'Normalising'
                    imshow(Norm,'parent',app.UIAxes4);

                case 'Image Sharpening'
                    imshow(app.Isharp,'parent',app.UIAxes4);

                case 'Red Channel Corrected'
                    imshow(Irc,'parent',app.UIAxes4);

                case 'Blue Channel Corrected'
                    imshow(Ibc,'parent',app.UIAxes4);
                
                case 'Multi-Scale Fusion'
                    imshow(fusion,'parent',app.UIAxes4);
                
                case  'CLAHE'
                    imshow(CLAHE,'parent',app.UIAxes4);

                otherwise
                    imshow("ERR.png",'parent',app.UIAxes4);
 end


%%
[~, snr] = psnr(CLAHE,rgbImage);
peaksnr = psnr_1(CLAHE,rgbImage);
app.SNREditField.Value = snr;
app.PeakSNREditField.Value = peaksnr;

ssimval = ssim(CLAHE,rgbImage);
app.StructuralsimilaritySSIMindexEditField.Value = ssimval;

err = immse(CLAHE,rgbImage);
app.MeansquarederrorMSEEditField.Value = err;

OriginalImage = brisque(rgbImage);
app.NoreferenceimageQualityscoreofOriginalImageEditField.Value = OriginalImage;

SharpenedImage = brisque(CLAHE);
app.NoreferenceimageQualityscoreofSharpenedImageEditField.Value = SharpenedImage;


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'INPUT IMAGE')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'ENHANCED IMAGE')
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'HISTOGRAM')
            xlabel(app.UIAxes3, 'Value of Pixel')
            ylabel(app.UIAxes3, 'Number of Pixels')
            app.UIAxes3.XLim = [0 256];
            app.UIAxes3.XTickLabel = {'0'; '4'; '16'; '64'; '128'; '256'};
            app.UIAxes4 = uiaxes(app.UIFigure);
            title(app.UIAxes4, 'TECHNIQUE')
            app.UIAxes4.XTick = [];
            app.UIAxes4.YTick = [];
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.CONVERTButton = uibutton(app.UIFigure, 'push');
            app.CONVERTButton.ButtonPushedFcn = createCallbackFcn(app, @convert, true);
            app.CONVERTButton.Text = 'CONVERT';
            app.INSERTButton = uibutton(app.UIFigure, 'push');
            app.INSERTButton.ButtonPushedFcn = createCallbackFcn(app, @ImgSelect, true);
            app.INSERTButton.Text = 'INSERT';
            app.CorrectionFactorAlphaSliderLabel = uilabel(app.UIFigure);
            app.CorrectionFactorAlphaSliderLabel.Text = 'Correction Factor (Alpha)';
            app.CorrectionFactorAlphaSlider = uislider(app.UIFigure);
            app.CorrectionFactorAlphaSlider.Limits = [0 1];
            app.SigmaKnobLabel = uilabel(app.UIFigure);
            app.SigmaKnobLabel.Text = 'Sigma';
            app.SigmaKnob = uiknob(app.UIFigure, 'continuous');
            app.IterationSliderLabel = uilabel(app.UIFigure);
            app.IterationSliderLabel.Text = 'Iteration';
            app.IterationSlider = uislider(app.UIFigure);
            app.TECHNIQUEDropDown = uidropdown(app.UIFigure);
            app.TECHNIQUEDropDown.Items = {'White Balancing', 'Gamma Correction', 'Normalising', 'Image Sharpening', 'Red Channel Corrected', 'Blue Channel Corrected', 'Multi-Scale Fusion', 'CLAHE'};
            app.TECHNIQUEDropDown.Value = 'Blue Channel Corrected';
            app.CONTROLSLabel = uilabel(app.UIFigure);
            app.CONTROLSLabel.Text = 'CONTROLS';
            app.SNREditFieldLabel = uilabel(app.UIFigure);
            app.SNREditFieldLabel.Text = 'SNR';
            app.SNREditField = uieditfield(app.UIFigure, 'numeric');
            app.SNREditField.ValueChangedFcn = createCallbackFcn(app, @convert, true);
            app.TECHNIQUEDropDownLabel = uilabel(app.UIFigure);
            app.TECHNIQUEDropDownLabel.Text = 'TECHNIQUE';
            app.IMAGEQUALITYMATRIXLabel = uilabel(app.UIFigure);
            app.IMAGEQUALITYMATRIXLabel.Text = 'IMAGE QUALITY MATRIX';
            app.PeakSNREditFieldLabel = uilabel(app.UIFigure);
            app.PeakSNREditFieldLabel.Text = 'Peak SNR';
            app.PeakSNREditField = uieditfield(app.UIFigure, 'numeric');
            app.StructuralsimilaritySSIMindexLabel = uilabel(app.UIFigure);
            app.StructuralsimilaritySSIMindexLabel.Text = {'Structural similarity '; '(SSIM) index'};
            app.StructuralsimilaritySSIMindexEditField = uieditfield(app.UIFigure, 'numeric');
            app.MeansquarederrorMSEEditFieldLabel = uilabel(app.UIFigure);
            app.MeansquarederrorMSEEditFieldLabel.Text = 'Mean-squared error (MSE)';
            app.MeansquarederrorMSEEditField = uieditfield(app.UIFigure, 'numeric');
            app.eferenceimageQualityscoreofOriginalImageLabel = uilabel(app.UIFigure);
            app.eferenceimageQualityscoreofOriginalImageLabel.Text = {'No reference image '; 'Quality score '; 'of Original Image'};
            app.NoreferenceimageQualityscoreofOriginalImageEditField = uieditfield(app.UIFigure, 'numeric');
            app.NoreferenceimageQualityscoreofSharpenedImageLabel = uilabel(app.UIFigure);
            app.NoreferenceimageQualityscoreofSharpenedImageLabel.Text = {'No reference image'; 'Quality score '; 'of Sharpened Image'};
            app.NoreferenceimageQualityscoreofSharpenedImageEditField = uieditfield(app.UIFigure, 'numeric');
            app.UNDERWATERIMAGEENHANCEMENTLabel_2 = uilabel(app.UIFigure);
            app.UNDERWATERIMAGEENHANCEMENTLabel_2.Text = 'UNDERWATER IMAGE ENHANCEMENT';
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            app.AlphaEditField = uieditfield(app.UIFigure, 'numeric');
            app.SigmaEditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = UWI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end